classdef SCSfallDetection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        FallCounterValue       matlab.ui.control.Label
        FallCounterLabel       matlab.ui.control.Label
        StartserverButton      matlab.ui.control.Button
        ConnectionLabel        matlab.ui.control.Label
        ConnectionLamp         matlab.ui.control.Lamp
        ExportFallDataButton   matlab.ui.control.Button
        StableLabel            matlab.ui.control.Label
        Lamp                   matlab.ui.control.Lamp
        StartMonitoringButton  matlab.ui.control.Button
        FALLDETECTIONLabel     matlab.ui.control.Label
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Timer
        Data 
        FallSound              
        FallSoundFs 
        TCPServer
        connectionTimer
    end

    
    methods (Access = private)

       function checkConnection(app)
           if app.TCPServer.Connected
               app.ConnectionLabel.Text = 'Connected';
               app.ConnectionLamp.Color = 'green';
           else
               app.ConnectionLabel.Text = 'Disconnected';
               app.ConnectionLamp.Color = 'red';
               stop(app.connectionTimer);

               
               delete(app.TCPServer);
               disp('Server closed.');
               app.StartserverButton.Enable = "on";
               app.StartMonitoringButton.Enable = "off";
           end    
       end    
       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            startServer(app);
        end

        % Callback function
        function UIAxesButtonDown(app, event)

        end

        % Button pushed function: StartMonitoringButton
        function StartMonitoringButtonPushed(app, event)
             evalin('base', 'clear');

            [app.FallSound, app.FallSoundFs] = audioread('alertsound.wav');
            app.Data = [];
            bufferSize = 15; % Window size for 3 seconds of data at 5 samples per second
            accBuffer = [];
            gyroBuffer = [];
            app.FallCounterValue.Text = '0';
            counter = 0;

            fall_detected_history = zeros(1, 100);  % Initialize with zeros or appropriate size
            app.StartMonitoringButton.Enable = "off";

            try
                load('training_statistics.mat');
                
                % Loop to continuously receive data
                while true
                    if ~app.TCPServer.Connected
                       break;
                    end   
                    % Wait for data to be available
                    while app.TCPServer.NumBytesAvailable == 0
                        pause(0.2); % Pause for 0.2 seconds to align with 5 samples per second
                    end
            
                    % Read data from the client (Matlab Mobile)
                    dataReceived = readline(app.TCPServer);
            
                    % Extract accelerometer (ACC) and gyroscope (GYRO) data from received string
                    dataParts = split(dataReceived, ';');
                    accStr = extractAfter(dataParts{1}, 'ACC:');
                    gyroStr = extractAfter(dataParts{2}, 'GYRO:');
            
                    % Convert ACC and GYRO data to numeric arrays
                    accData = str2double(split(accStr, ','));
                    gyroData = str2double(split(gyroStr, ','));
            
                    % Ensure the data is in the correct format
                    if length(accData) == 3 && length(gyroData) == 3
                        accMagnitude = sqrt(sum(accData.^2));
                        gyroMagnitude = sqrt(sum(gyroData.^2));
            
                        % Append the received data and timestamp to the buffers
                        accBuffer = [accBuffer; accMagnitude];
                        gyroBuffer = [gyroBuffer; gyroMagnitude];
                    else
                        disp('Received data has incorrect dimensions:');
                        disp(['accData: ', num2str(accData)]);
                        disp(['gyroData: ', num2str(gyroData)]);
                    end
            
                    % Check if we have enough data for a 3-second window (15 samples at 5 samples/second)
                    windowSize = 15;
                    if size(accBuffer, 1) >= windowSize && size(gyroBuffer, 1) >= windowSize
                        % Extract the required window
                        segmentAcc = accBuffer(1:windowSize);
                        segmentGyro = gyroBuffer(1:windowSize);
                        % Calculate the maximum acceleration magnitude and gyroscope magnitude at specific indices
                        firstSecondIndex = 1; % 1st second index in a 3-second window assuming 5 samples per second
                        secondSecondIndex = 5; % 2nd second index in a 3-second window
                        thirdSecondIndex = 10;
            
                        % Maximum acceleration magnitude
                        accMagnitudes = sqrt(sum(segmentAcc.^2, 2));
                        gyroMagnitudes = sqrt(sum(segmentGyro.^2, 2));
                        maxAccMagnitude = max(accMagnitudes);
            
                        acc_max = max(segmentAcc(secondSecondIndex-1:secondSecondIndex+3));
                        lin_max = max(segmentAcc(secondSecondIndex-1:secondSecondIndex+3));
                        post_lin_max = max(segmentAcc(thirdSecondIndex-1:thirdSecondIndex+3));
                        gyro_max = max(segmentGyro(secondSecondIndex-1:secondSecondIndex+3));
                        post_gyro_max = max(segmentGyro(thirdSecondIndex-1:thirdSecondIndex+3));
                        % Calculate kurtosis and skewness for the whole window
                        acc_kurtosis = kurtosis(segmentAcc);
                        acc_skewness = skewness(segmentAcc);
                        gyro_kurtosis = kurtosis(segmentGyro);
                        gyro_skewness = skewness(segmentGyro);
                        
                        % Feature normalization using loaded training statistics
                        % normalized_acc_max = (acc_max - mean_training(1)) / std_training(1);
                        % normalized_gyro_max = (gyro_max - mean_training(2)) / std_training(2);
                        % normalized_acc_kurtosis = (acc_kurtosis - mean_training(3)) / std_training(3);
                        % normalized_gyro_kurtosis = (gyro_kurtosis - mean_training(4)) / std_training(4);
                        % normalized_lin_max = (lin_max - mean_training(5)) / std_training(5);
                        % normalized_acc_skewness = (acc_skewness - mean_training(6)) / std_training(6);
                        % normalized_gyro_skewness = (gyro_skewness - mean_training(7)) / std_training(7);
                        % normalized_post_gyro_max = (post_gyro_max - mean_training(8)) / std_training(8);
                        % normalized_post_lin_max = (post_lin_max - mean_training(9)) / std_training(9);
            
                        % Construct the feature vector
                        features = [acc_max, gyro_max, acc_kurtosis, gyro_kurtosis, ...
                                    lin_max, acc_skewness, gyro_skewness, ...
                                    post_gyro_max, post_lin_max];
                        % 
                        % features = [normalized_acc_max, normalized_gyro_max, normalized_acc_kurtosis,...
                        %             normalized_gyro_kurtosis, normalized_lin_max, normalized_acc_skewness,...
                        %             normalized_gyro_skewness, normalized_post_gyro_max, normalized_post_lin_max];
                        % 

                        % Load your pre-trained model (replace 'your_model.mat' with your actual model file)
                        load('fall_detection_model.mat');  % Load your saved model
            
                        % Predict using your pre-trained model
                        prediction = predict(mdl, features);  % Ensure your_model.predict is correctly defined
                        fall_detected_history = [fall_detected_history(2:end), str2double(prediction)];
            
                        % Display prediction result (example, modify based on your model's output)
                        if str2double(prediction) == 1
                            disp('Fall detected!');
                            sound(app.FallSound, app.FallSoundFs);
                            app.StableLabel.Text = 'Fall Detected';
                            app.Lamp.Color = 'red';
                            counter = counter + 1;
                            app.FallCounterValue.Text = num2str(counter);
                        else
                            disp('No fall detected.');
                            app.StableLabel.Text = 'Stable';
                            app.Lamp.Color = 'green';
                        end
            
                        % set(plotHandle, 'YData', fall_detected_history);
                        
                        % app.Data = [app.Data, fall_detected_history];
                        app.Data = fall_detected_history;
                        % Update the graph
                        plot(app.UIAxes, app.Data, 'r-');
                        % drawnow;
            
                        % Pause for a short duration to simulate real-time updating
                        pause(0.1);  % Adjust pause duration as needed
                        % Force MATLAB to update the plot immediately
                        drawnow;
            
                        % Remove the processed segment from the buffer
                        accBuffer(1:windowSize) = [];
                        gyroBuffer(1:windowSize) = [];
                    end
                end
            catch ME
                disp('Error occurred:');
                disp(ME.message);
            end

            % Create and start the timer
            % app.Timer = timer(...
            %     'ExecutionMode', 'fixedRate', ...   % Run timer repeatedly
            %     'Period', 1, ...                    % Timer period in seconds
            %     'TimerFcn', @(~,~)updateGraph(app)); % Timer callback function
            % start(app.Timer);
        end

        % Button pushed function: StartserverButton
        function startServer(app, event)
             % TCP/IP Server Configuration
            localIP = '0.0.0.0';    % Listen on all available network interfaces
            serverPort = 55000;     % Port number for communication (must match client)
            
            % Create TCP/IP server socket
            app.TCPServer = tcpserver(localIP, serverPort);
            
            % Start listening for connections
            disp('Server started, waiting for client connection...');
            % Create and start the timer
            app.connectionTimer = timer('ExecutionMode', 'fixedRate', 'Period', 2, ...  % Check every 2 seconds
                                    'TimerFcn', @(~, ~) checkConnection(app));
                    
            while isvalid(app) && true
                    if app.TCPServer.Connected
                        disp('Client connected!');
                        start(app.connectionTimer);
                        app.ConnectionLabel.Text = 'Connected';
                        app.ConnectionLamp.Color = 'green';
                        app.StartMonitoringButton.Enable = "on";
                         % Start the timer
                        break;
                    else
                        app.StartserverButton.Enable = "off";
                        app.StartMonitoringButton.Enable = "off";
                        app.ConnectionLabel.Text = 'Waiting for connection';
                        app.ConnectionLamp.Color = 'red';    
                    end
                    pause(0.1); % Wait for a short period before checking again
            end

            
        end

        % Close request function: UIFigure
        function onAppClose(app, event)
            stop(app.connectionTimer);
            delete(app.TCPServer);  % Delete TCP/IP object
            disp('Server closed.');
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @onAppClose, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Real-Time Fall Detection Results')
            xlabel(app.UIAxes, 'Time (s)')
            app.UIAxes.YLim = [-0.5 1.5];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [0 1];
            app.UIAxes.YTickLabel = {'No Fall'; 'Fall Detected'};
            app.UIAxes.ZTick = [0 0.5 1];
            app.FALLDETECTIONLabel = uilabel(app.UIFigure);
            app.FALLDETECTIONLabel.Text = 'FALL DETECTION';
            app.StartMonitoringButton = uibutton(app.UIFigure, 'push');
            app.StartMonitoringButton.ButtonPushedFcn = createCallbackFcn(app, @StartMonitoringButtonPushed, true);
            app.StartMonitoringButton.Enable = 'off';
            app.StartMonitoringButton.Text = 'Start Monitoring';
            app.Lamp = uilamp(app.UIFigure);
            app.StableLabel = uilabel(app.UIFigure);
            app.StableLabel.Text = 'Stable';
            app.ExportFallDataButton = uibutton(app.UIFigure, 'push');
            app.ExportFallDataButton.Enable = 'off';
            app.ExportFallDataButton.Text = 'Export Fall Data';
            app.ConnectionLamp = uilamp(app.UIFigure);
            app.ConnectionLabel = uilabel(app.UIFigure);
            app.ConnectionLabel.Text = 'Waiting for connection';
            app.StartserverButton = uibutton(app.UIFigure, 'push');
            app.StartserverButton.ButtonPushedFcn = createCallbackFcn(app, @startServer, true);
            app.StartserverButton.Text = 'Start server';
            app.FallCounterLabel = uilabel(app.UIFigure);
            app.FallCounterLabel.Text = 'Fall Counter:';
            app.FallCounterValue = uilabel(app.UIFigure);
            app.FallCounterValue.Text = '0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SCSfallDetection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end